classdef Ecoli_geneExpression_TASEP_dataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportcurrentTabMenu            matlab.ui.container.Menu
        ExportalltabsMenu               matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        InputRatesTab                   matlab.ui.container.Tab
        SimulationinputparametersPanel  matlab.ui.container.Panel
        PromotorontimeEditField         matlab.ui.control.NumericEditField
        PromotorontimeEditFieldLabel    matlab.ui.control.Label
        sLabel_6                        matlab.ui.control.Label
        lociLabel                       matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        ntsLabel_2                      matlab.ui.control.Label
        s1Label_3                       matlab.ui.control.Label
        s1Label_2                       matlab.ui.control.Label
        s1Label                         matlab.ui.control.Label
        ntsLabel                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        HoursLabel                      matlab.ui.control.Label
        EstimatedtImeremainingLabel     matlab.ui.control.Label
        muPT1kdPTEditField              matlab.ui.control.NumericEditField
        muPT1kdPTLabel                  matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
        SelectsavefolderButton          matlab.ui.control.Button
        NumberofiterationsEditField     matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        IdleLabel                       matlab.ui.control.Label
        StartsimulationButton           matlab.ui.control.Button
        RholoadingrateEditField         matlab.ui.control.NumericEditField
        RholoadingrateEditFieldLabel    matlab.ui.control.Label
        mu11kd1EditField                matlab.ui.control.NumericEditField
        mu11kd1Label                    matlab.ui.control.Label
        RibosomespeedEditField          matlab.ui.control.NumericEditField
        RibosomespeedEditFieldLabel     matlab.ui.control.Label
        RibosomeloadingrateEditField    matlab.ui.control.NumericEditField
        RibosomeloadingrateEditFieldLabel  matlab.ui.control.Label
        mu21kd2EditField                matlab.ui.control.NumericEditField
        mu21kd2Label                    matlab.ui.control.Label
        PromotorofftimeEditField        matlab.ui.control.NumericEditField
        PromotorofftimeEditFieldLabel   matlab.ui.control.Label
        TotalsimulationtimeEditField    matlab.ui.control.NumericEditField
        TotalsimulationtimeEditFieldLabel  matlab.ui.control.Label
        RNAPloadingrateEditField        matlab.ui.control.NumericEditField
        RNAPloadingrateEditFieldLabel   matlab.ui.control.Label
        RNAPspeedEditField              matlab.ui.control.NumericEditField
        RNAPspeedEditFieldLabel         matlab.ui.control.Label
        InputRNAPdwelltimeTab           matlab.ui.container.Tab
        GenelengthEditField             matlab.ui.control.NumericEditField
        GenelengthEditFieldLabel        matlab.ui.control.Label
        ImportdwelltimedistributionButton  matlab.ui.control.Button
        RNAPprofileDropDown             matlab.ui.control.DropDown
        RNAPprofileDropDownLabel        matlab.ui.control.Label
        dwelltime_Axes                  matlab.ui.control.UIAxes
        ExtraBatchsimulationTab         matlab.ui.container.Tab
        SelectgenefolderButton          matlab.ui.control.Button
        batchSimTable                   matlab.ui.control.Table
        CurrentgenesimulationLabel      matlab.ui.control.Label
        StartbatchsimulationButton      matlab.ui.control.Button
        batchIdleLabel                  matlab.ui.control.Label
        batchGauge                      matlab.ui.control.LinearGauge
        ImportparameterfileButton       matlab.ui.control.Button
        DataviewerPanel                 matlab.ui.container.Panel
        ImportallfilesfromfolderButton  matlab.ui.control.Button
        FilenameDropDown                matlab.ui.control.DropDown
        FilenameDropDownLabel           matlab.ui.control.Label
        ImportdataButton                matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SEndseqTab                      matlab.ui.container.Tab
        sendSEQ_Axes                    matlab.ui.control.UIAxes
        sendSEQcoverage_Axes            matlab.ui.control.UIAxes
        RifseqTab                       matlab.ui.container.Tab
        HoldgraphCheckBox_2             matlab.ui.control.CheckBox
        rifseq_Axes                     matlab.ui.control.UIAxes
        NETseqTab                       matlab.ui.container.Tab
        NETseqtimesEditField            matlab.ui.control.NumericEditField
        NETseqtimesEditFieldLabel       matlab.ui.control.Label
        NETseq_dropdown                 matlab.ui.control.DropDown
        PlotDropDownLabel_2             matlab.ui.control.Label
        NETseq_Axes                     matlab.ui.control.UIAxes
        ParametersTab                   matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        ParametersNode                  matlab.ui.container.TreeNode
        VelocitiesNode                  matlab.ui.container.TreeNode
        v1Node                          matlab.ui.container.TreeNode
        v2Node                          matlab.ui.container.TreeNode
        RiboSpeedNode                   matlab.ui.container.TreeNode
        KvaluesNode                     matlab.ui.container.TreeNode
        KRNAPLoadingNode                matlab.ui.container.TreeNode
        KRiboLoadingNode                matlab.ui.container.TreeNode
        KRUTLoadingNode                 matlab.ui.container.TreeNode
        MuValuesNode                    matlab.ui.container.TreeNode
        coTranscriptionalMuNode         matlab.ui.container.TreeNode
        postTranscriptionalMuNode       matlab.ui.container.TreeNode
        ProteinmuNode                   matlab.ui.container.TreeNode
        OtherParametersNode             matlab.ui.container.TreeNode
        GlucoseAdditionTimeNode         matlab.ui.container.TreeNode
        NumberoflociNode                matlab.ui.container.TreeNode
        RiboFollowingNode               matlab.ui.container.TreeNode
        SimulationTimeNode              matlab.ui.container.TreeNode
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Code written by: Albur Hassan
    % code written in: Matlab App designer.
    % last updated: 16th December 2025.
    % contact: ahassan4@illinois.edu
    %
    % this code is only for the gui you will need the accompanying TASEP
    % code to run the simulation
   
    properties (Access = private)
        parameterList =[]; % Description
        fish_signals= zeros(2000,2,2);
        fish_signals_err = zeros(2000,2,2);% Description
        savepath=pwd; % Description
        data; % Description
        imported_dwelltime=ones(3075,1);
        imported_dwelltime_Name = "uniform";
        genepath;
        batchSimParam;
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            value = app.FilenameDropDown.Value;
            selectedTab = app.TabGroup.SelectedTab;
            if  isempty(value)
                return;
            end

            x= app.data(value).fishTime;

            %plot1 sendSEQ graph
            if selectedTab.Title=="SEnd-seq"
                sendSEQ = app.data(value).Sendseq;
                diff = sendSEQ(:,2) -sendSEQ(:,1);
                sendSEQ = sendSEQ(diff>0,1:3);
                geneLength= app.data(value).parameters.geneLength;
                xlim(app.sendSEQcoverage_Axes,[0,geneLength]);
                ylim(app.sendSEQcoverage_Axes,[0,0.1+1.1*max(app.data(value).RNAseq(:,1))]);
                plot(app.sendSEQcoverage_Axes,app.data(value).RNAseq(:,1),'b');
                sendSEQ = sortrows(sendSEQ,2);
                
                sendSEQN = sendSEQ(sendSEQ(:,3)==0,:);
                sendSEQR = sendSEQ(sendSEQ(:,3)==1,:);
                NascentRNA = size(sendSEQN,1);
                
                cla(app.sendSEQ_Axes);
                hold(app.sendSEQ_Axes,'on');
                for i = 1:NascentRNA
	                x = [sendSEQN(i,1) , sendSEQN(i,2) ,sendSEQN(i,2) ,sendSEQN(i,1)];
	                y= [i-1, i-1, i ,i];
	                fill(app.sendSEQ_Axes,x,y,[0.2 0.7 0.2],'EdgeColor','none','FaceAlpha',0.7);
                end
                ReleasedRNA = size(sendSEQR,1);
                
                sendSEQR1 = sendSEQR(sendSEQR(:,2)>= geneLength,:);
                sendSEQR1 = sortrows(sendSEQR1,1);
                sendSEQR2 = sendSEQR(sendSEQR(:,2)< geneLength,:);
                sendSEQR2 = sortrows(sendSEQR2,1);
                sendSEQR = [sendSEQR2;sendSEQR1];
                ReleasedRNA2 = size(sendSEQR2,1);
                for i = 1:ReleasedRNA
	                x = [sendSEQR(i,1) , sendSEQR(i,2) ,sendSEQR(i,2) ,sendSEQR(i,1)];
	                y= [i-1+NascentRNA, i-1+NascentRNA, i+NascentRNA ,i+NascentRNA];
	                C= [0.9 0.1 0.1];
	                if i < ReleasedRNA2
		                C= [0.9 0.45 0.1];
	                end
	                fill(app.sendSEQ_Axes,x,y,C,'EdgeColor','none','FaceAlpha',0.7);
                end
                xlim(app.sendSEQ_Axes,[0,geneLength]);
                %ylim([0,6250]);
                
            %plot2 rifSEQ plot
            elseif selectedTab.Title=="Rif-seq"
                if(app.HoldgraphCheckBox_2.Value==1)
                    hold (app.rifseq_Axes,"on");
                else
                    hold (app.rifseq_Axes,"off");
                end
                tempY= sum(app.data(value).crazyfish(:,:),1);
                tempY= log(tempY/max(tempY));
                plot(app.rifseq_Axes,[1:1:size(app.data(value).crazyfish,2)],tempY  ...
                    ,'DisplayName',sprintf("mu_1: %g mu_2: %g mu_{PT}: %g PT: %g k_{ribo}: %g",app.data(value).parameters.mu1,app.data(value).parameters.mu2,app.data(value).parameters.muPT,app.data(value).parameters.KRutLoading,app.data(value).parameters.kRiboLoading)...
                    ,'LineWidth',1.5);
                legend(app.rifseq_Axes,'Location','south');
                xlim(app.rifseq_Axes,[app.data(value).parameters.glutime,app.data(value).parameters.simtime]);
                ylim(app.rifseq_Axes,[tempY(end),0.1]);

             %plot 4 NETSEQ plot.
             elseif selectedTab.Title == "NET-seq"
                DropDown_value=app.NETseq_dropdown.Value;
                if DropDown_value==1
                    timeSelected = app.NETseqtimesEditField.Value;
                    x= [1:1:size(app.data(value).NETseq,1)];
                    plot(app.NETseq_Axes,x,app.data(value).NETseq(:,timeSelected),'b','DisplayName',"NETseq");
                elseif DropDown_value==2
                    x= [1:1:size(app.data(value).NETseq,1)];
                    y= sum(app.data(value).NETseq(:,250:200:1500),2);
                    plot(app.NETseq_Axes,x,y,'b','DisplayName',"NETseq ");
                end
             end
        end

        function updateTree(app)
            value = app.FilenameDropDown.Value;
            current_parameters = app.data(value).parameters;
		    app.ParametersNode.Children.delete;

			uitreenode(app.ParametersNode,'Text',"RNAP speed : " + current_parameters.avgSpeed1 +" nt/s");
			uitreenode(app.ParametersNode,'Text',"Ribosome speed : " + current_parameters.ribospeed+" nt/s");
			uitreenode(app.ParametersNode,'Text',"Number of iterations : " + app.data(value).nloci+" loci");
			uitreenode(app.ParametersNode,'Text',"Promotor on time : 0 s" );
			uitreenode(app.ParametersNode,'Text',"Promotor on time : " + current_parameters.glutime + " s");
			uitreenode(app.ParametersNode,'Text',"Total simulation time : " + current_parameters.simtime + " s");
			uitreenode(app.ParametersNode,'Text',"RNAP loading rate : " + current_parameters.kLoading + " s^-1");
			uitreenode(app.ParametersNode,'Text',"Ribosome loading rate : " + current_parameters.kRiboLoading+ " s^-1");
			uitreenode(app.ParametersNode,'Text',"Rho loading rate : " + current_parameters.KRutLoading+ " s^-1");
			uitreenode(app.ParametersNode,'Text',"mu1 (1/kd1) : " + current_parameters.mu1+ " s");
			uitreenode(app.ParametersNode,'Text',"mu2 (1/kd2) : " + current_parameters.mu2+ " s");
			uitreenode(app.ParametersNode,'Text',"muPT (1/kdPT) : " + current_parameters.muPT+ " s");
            
            expand(app.ParametersNode,'all');

        end
        
        function specificDwelltime = CreateDwelltimearray(app,RNAP_Profile)
            %RNAP_Profile= app.RNAPprofileDropDown.Value;
            geneLength= app.GenelengthEditField.Value; dx=1;
            specificDwelltime = ones(geneLength/dx,1);
            if RNAP_Profile ==0
		        %do nothing This is uniform dwell time profile.
            elseif RNAP_Profile ==1
                specificDwelltime=app.imported_dwelltime;
            end
            specificDwelltime = specificDwelltime/mean(specificDwelltime);
            plot(app.dwelltime_Axes,specificDwelltime);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.mat','All Matlab Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               load(fname);
               size(app.FilenameDropDown.ItemsData,2);
               if size(app.FilenameDropDown.ItemsData,1) <1
                   app.data = final_output;
               else
                    app.data(size(app.data,2)+1) = final_output; 
               end
               ddItems = "";%cell(size(app.data,2), 1);
               for ii = 1 : 1 : size(app.data,2)   %length(ddItems)
                    ddItems(ii) = app.data(ii).filename;
               end
               app.FilenameDropDown.Items = ddItems;
               app.FilenameDropDown.ItemsData =[1:length(ddItems)];
               updatePlots(app);
               updateTree(app);
               %hold (app.rifseq_Axes,"off");
               %plot(app.rifseq_Axes,totalfish1 ,'-b');
               %hold (app.rifseq_Axes,"on");
	           %plot(app.rifseq_Axes,totalfish2 ,'-r');
               %hold (app.rifseq_Axes,"off");
            end

        end

        % Value changed function: FilenameDropDown
        function FilenameDropDownValueChanged(app, event)
            value = app.FilenameDropDown.Value;
            if value >=1
                updatePlots(app);
                updateTree(app);
            end
        end

        % Button pushed function: ImportallfilesfromfolderButton
        function ImportallfilesfromfolderButtonPushed(app, event)
            filterspec = {'*.mat','All Matlab Files'};
            selpath = uigetdir;
            File =dir(fullfile(selpath, '*.mat'));
            FileNames = {File.name}';
            Length_Names = size(FileNames,1);
            for i=1:Length_Names
               fname=strcat(selpath+"/", FileNames{i});% if ubuntu use / if windows use \
               % Make sure user didn't cancel uigetfile dialog
               load(fname);
               if size(app.FilenameDropDown.ItemsData,1) <1
                   app.data = final_output;
               else
                    app.data(size(app.data,2)+1) = final_output; 
               end
               ddItems = "";%cell(size(app.data,2), 1);
               for ii = 1 : 1 : size(app.data,2)   %length(ddItems)
                    ddItems(ii) = app.data(ii).filename;
               end
               app.FilenameDropDown.Items = ddItems;
               app.FilenameDropDown.ItemsData =[1:length(ddItems)];
               updatePlots(app);
               %hold (app.rifseq_Axes,"off");
               %plot(app.rifseq_Axes,totalfish1 ,'-b');
               %hold (app.rifseq_Axes,"on");
               %plot(app.rifseq_Axes,totalfish2 ,'-r');
               %hold (app.rifseq_Axes,"off");
            end

        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            param =struct;
            param.avgSpeed1 = app.RNAPspeedEditField.Value;
            param.avgSpeed2 = app.RNAPspeedEditField.Value;
            param.kLoading = app.RNAPloadingrateEditField.Value;
            param.simtime = app.TotalsimulationtimeEditField.Value;
            param.glutime = app.PromotorofftimeEditField.Value;
            param.ribospeed = app.RibosomespeedEditField.Value;
            param.kRiboLoading = app.RibosomeloadingrateEditField.Value;
            param.KRutLoading = app.RholoadingrateEditField.Value;
            param.mu1 = app.mu11kd1EditField.Value;
            param.mu2 = app.mu21kd2EditField.Value;
            param.muPT = app.muPT1kdPTEditField.Value;
            param.muProtein = 50;
            param.ribofollowing = 2;
            param.RNAP_Profile = 2;
            param.Random_Model = 0;
            param.boolRNAPRiboCoupling = 1;
            param.PT_Model = 2;
            param.geneLength =app.GenelengthEditField.Value;
            nloci =app.NumberofiterationsEditField.Value;
            param.RNAP_dwellTimeProfile= CreateDwelltimearray(app,app.RNAPprofileDropDown.Value);
            app.IdleLabel.Text = "simulation running";
            app.Gauge.Limits =[0,nloci];
            app.Gauge.ScaleColorLimits =[0,nloci/10; 9*nloci/10 ,nloci];
            drawnow;
            %%firstfish =[];
            output = Ecoli_geneExpression_TASEP_function(param);
            app.Gauge.Value = 1;
            final_output = output;
            totalTimeElapsed = output.timeElapsed;
            fish1_signals = zeros(size(output.fishTime,1),nloci,2);
            fish1_signals(:,1,1) = output.fish5Signal;
            fish1_signals(:,1,2) = output.fish3Signal;
            gstar_signals(:,1) = output.Gstar_Signal;
            gstar_fishSignals =output.Gstar_fishSignals;
            final_output.allProteinSignals=zeros(size(output.fishTime,1),nloci);
            final_output.allProteinSignals(:,1)= output.proteinSignal;
            for i =2:nloci
	            output = Ecoli_geneExpression_TASEP_function(param);
                % if output.Gstar_fishSignals(end-1,1) > 0 || output.Gstar_fishSignals(end-1,2) > 0
                %     filename ="" + string(datetime('now','Format','yyyyMMdd_HHmm'))+"_anomalyDetected";
                %     output.filename = filename;
                %     save((app.savepath +"\"+ filename+".mat"),'output');
                % end
	            fish1_signals(:,i,1) = output.fish5Signal;
	            fish1_signals(:,i,2) = output.fish3Signal;
                final_output.allProteinSignals(:,i)= output.proteinSignal;
                gstar_signals(:,i) = output.Gstar_Signal;
                gstar_fishSignals = gstar_fishSignals +output.Gstar_fishSignals;
	            final_output.proteinSignal = final_output.proteinSignal + output.proteinSignal;
	            final_output.transcribing_5signal = final_output.transcribing_5signal +output.transcribing_5signal;
	            final_output.DNAStats(:,i) = output.DNAStats;
	            final_output.FirstRiboRNAPdistance = [final_output.FirstRiboRNAPdistance;output.FirstRiboRNAPdistance];
	            final_output.RiboperRNAP = [final_output.RiboperRNAP;output.RiboperRNAP];
                final_output.RNAP_startTimes = [final_output.RNAP_startTimes,output.RNAP_startTimes];
                final_output.RNAP_endTimes = [final_output.RNAP_endTimes,output.RNAP_endTimes];
                final_output.RNAlifetimes = [final_output.RNAlifetimes,output.RNAlifetimes];
	            final_output.prematureterm = final_output.prematureterm + output.prematureterm;
	            final_output.RNAlifetimes_analysis =[final_output.RNAlifetimes_analysis,output.RNAlifetimes_analysis];
	            final_output.mu1lifetimes =[final_output.mu1lifetimes,output.mu1lifetimes];
	            final_output.mu2lifetimes =[final_output.mu2lifetimes,output.mu2lifetimes];
                final_output.ribo_loadTimes = [final_output.ribo_loadTimes;output.ribo_loadTimes];
                final_output.ribo_loadTimes2 = [final_output.ribo_loadTimes2;output.ribo_loadTimes2];
                final_output.RNAseq = final_output.RNAseq + output.RNAseq;
                final_output.crazyfish = final_output.crazyfish +output.crazyfish;
                final_output.crazyGstar = final_output.crazyGstar +output.crazyGstar;
                final_output.NETseq = final_output.NETseq + output.NETseq;
                final_output.Sendseq = [final_output.Sendseq;output.Sendseq];
                final_output.gstar_5_breakdown = final_output.gstar_5_breakdown + output.gstar_5_breakdown;
                final_output.gstar_3_breakdown = final_output.gstar_3_breakdown + output.gstar_3_breakdown;
                final_output.gstar_bp1_breakdown = final_output.gstar_bp1_breakdown + output.gstar_bp1_breakdown;
                app.Gauge.Value = i;
                totalTimeElapsed = totalTimeElapsed + output.timeElapsed;
                estimatedHoursRemaining = totalTimeElapsed/3600 * (app.NumberofiterationsEditField.Value - i)/i;
                % estimatedHoursRemaining = round(estimatedHoursRemaining,2);
                app.HoursLabel.Text = num2str(estimatedHoursRemaining) + " hours";
                drawnow;
            end
            final_output.crazyfish = final_output.crazyfish/nloci;
            final_output.crazyGstar = final_output.crazyGstar/nloci;
            final_output.fish5Signal = mean (fish1_signals(:,:,1),2);
            final_output.fish5FanoFactor = var(fish1_signals(:,:,1),0,2)./final_output.fish5Signal;
            final_output.fish3Signal = mean (fish1_signals(:,:,2),2);
            final_output.fish3FanoFactor = var(fish1_signals(:,:,2),0,2)./final_output.fish3Signal;
            final_output.GstarSignal = mean (gstar_signals(:,:),2);
            final_output.gstar_fishSignals =gstar_fishSignals/nloci;
            final_output.fish5Signalerr=std(fish1_signals(:,:,1),0,2)/sqrt(nloci);
            final_output.fish3Signalerr=std(fish1_signals(:,:,2),0,2)/sqrt(nloci);
            final_output.GstarSignalerr=std(gstar_signals(:,:),0,2)/sqrt(nloci);
            final_output.proteinSignal = final_output.proteinSignal/nloci;
            final_output.RNAseq = final_output.RNAseq/nloci;
            final_output.NETseq = final_output.NETseq/nloci;
            final_output.gstar_5_breakdown = final_output.gstar_5_breakdown/nloci;
            final_output.gstar_3_breakdown = final_output.gstar_3_breakdown/nloci;
            final_output.gstar_bp1_breakdown = final_output.gstar_bp1_breakdown/nloci;
            final_output.nloci = nloci;
            final_output.totalHoursElapsed = totalTimeElapsed/3600;
            filename ="" + string(datetime('now','Format','yyyyMMdd_HHmm'))+"_";
            if app.RNAPprofileDropDown.Value ==0
                filename =filename+ "uniform";
            else
                filename =filename+ app.imported_dwelltime_Name;
            end
            final_output.filename = filename;
            %%deets = mat2str(parameters);
            save((app.savepath +"/"+ filename+".mat"),'final_output');
            %%writelines(deets,("data\"+filename+".txt"));
            app.IdleLabel.Text = "simulation done";

        end

        % Button pushed function: SelectsavefolderButton
        function SelectsavefolderButtonPushed(app, event)
            app.savepath = uigetdir;
        end

        % Menu selected function: ExportalltabsMenu
        function ExportalltabsMenuSelected(app, event)
            selpath = uigetdir;
            for i =1:size(app.TabGroup.Children,1)
                app.TabGroup.SelectedTab = app.TabGroup.Children(i);
                updatePlots(app);
                updateTree(app);
                exportapp(app.UIFigure,strcat(selpath , '\tab',num2str(i),'.jpg'));
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
        end

        % Value changed function: NETseq_dropdown, NETseqtimesEditField
        function crazyfish_PlotDropDownValueChanged(app, event)
            updatePlots(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %selectedTab = app.TabGroup.SelectedTab;
            updatePlots(app);
        end

        % Button pushed function: ImportdwelltimedistributionButton
        function ImportdwelltimedistributionButtonPushed(app, event)
            filterspec = {'*.csv';'*.xlsx';'*.*'};
            [f, p] = uigetfile(filterspec);
             if (ischar(p))
               fname = [p f];
               import = readmatrix(fname, 'Range', 2);
               app.imported_dwelltime= import;
               fnamesplit= split(f,["_","."]);
               app.imported_dwelltime_Name = fnamesplit(2);
               app.GenelengthEditField.Value =length(import);
               app.RNAPprofileDropDown.Value=1;
               CreateDwelltimearray(app,1);
             end
        end

        % Value changed function: RNAPprofileDropDown
        function RNAPprofileDropDownValueChanged(app, event)
             value= app.RNAPprofileDropDown.Value;
             CreateDwelltimearray(app,value);
             
        end

        % Button pushed function: SelectgenefolderButton
        function SelectgenefolderButtonPushed(app, event)
            app.genepath = uigetdir;
        end

        % Button pushed function: ImportparameterfileButton
        function ImportparameterfileButtonPushed(app, event)
             filterspec = {'*.csv';'*.xlsx';'*.*'};
             [f, p] = uigetfile(filterspec);
             if (ischar(p))
               fname = [p f];
                % Script for importing data from the following text file:
                %
                %    filename: C:\albur\alburs code\matlab code\whatisSimulation\albur_TASEP\sjkimlab_albur_TASEP\batchSimParamaters.csv
                %
                % Auto-generated by MATLAB on 21-Oct-2025 15:25:16
                
                opts = delimitedTextImportOptions("NumVariables", 8);
                
                % Specify range and delimiter
                opts.DataLines = [2, Inf];
                opts.Delimiter = ",";

                % Specify column names and types
                opts.VariableNames = ["gene","RNAP_speed","Ribo_speed","number_iteration","total_simulation_time","Promotor_off_time","RNAP_loading_rate","Ribo_loading_rate","Rho_loading_rate","mu1","mu2","muPT"];
                opts.VariableTypes = ["string", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
                
                % Specify file level properties
                opts.ExtraColumnsRule = "ignore";
                opts.EmptyLineRule = "read";
                
                % Specify variable properties
                opts = setvaropts(opts, "gene", "WhitespaceRule", "preserve");
                opts = setvaropts(opts, "gene", "EmptyFieldRule", "auto");
                
                % Import the data
                app.batchSimParam = readtable(fname, opts);
                app.batchSimTable.ColumnName = opts.VariableNames;
                app.batchSimTable.Data = app.batchSimParam;
                
                clear opts
             end
             
             
        end

        % Button pushed function: StartbatchsimulationButton
        function StartbatchsimulationButtonPushed(app, event)
            param =struct;
            app.batchIdleLabel.Text = "simulation running";
            foldername ="" + string(datetime('now','Format','yyyyMMdd_HHmm'))+"_alburTASEP";
            mkdir(foldername);
            for geneNo =1:length(app.batchSimParam.gene)
                param.avgSpeed1 = app.batchSimParam.RNAP_speed(geneNo);
                param.avgSpeed2 = app.batchSimParam.RNAP_speed(geneNo);
                param.kLoading = app.batchSimParam.RNAP_loading_rate(geneNo);
                param.simtime = app.batchSimParam.total_simulation_time(geneNo);
                param.glutime = app.batchSimParam.Promotor_off_time(geneNo);
                param.ribospeed = app.batchSimParam.Ribo_speed(geneNo);
                param.kRiboLoading = app.batchSimParam.Ribo_loading_rate(geneNo);
                param.KRutLoading = app.batchSimParam.Rho_loading_rate(geneNo);
                param.mu1 = app.batchSimParam.mu1(geneNo);
                param.mu2 = app.batchSimParam.mu2(geneNo);
                param.muPT = app.batchSimParam.muPT(geneNo);
                param.muProtein = 50;
                param.ribofollowing = 2;
                param.RNAP_Profile = 2;
                param.Random_Model = 0;
                param.boolRNAPRiboCoupling = 1;
                param.PT_Model = 2;
                nloci =app.batchSimParam.number_iteration(geneNo);

                fname = app.genepath +"/NETSEQ_"+ app.batchSimParam.gene(geneNo)+".csv";

                importedDwelltime = readmatrix(fname, 'Range', 2);
                param.RNAP_dwellTimeProfile =importedDwelltime/mean(importedDwelltime);
                param.geneLength =length(importedDwelltime);


                app.batchGauge.Limits =[0,nloci];
                app.batchGauge.ScaleColorLimits =[0,nloci/10; 9*nloci/10 ,nloci];

                app.CurrentgenesimulationLabel.Text = "Current gene simulation: '" +app.batchSimParam.gene(geneNo) +"'";
                app.batchGauge.Value = 1;
                drawnow;
                %%firstfish =[];
                output = Ecoli_geneExpression_TASEP_function(param);
                final_output = output;
                totalTimeElapsed = output.timeElapsed;
                fish1_signals = zeros(size(output.fishTime,1),nloci,2);
                fish1_signals(:,1,1) = output.fish5Signal;
                fish1_signals(:,1,2) = output.fish3Signal;
                gstar_signals(:,1) = output.Gstar_Signal;
                gstar_fishSignals =output.Gstar_fishSignals;
                final_output.allProteinSignals=zeros(size(output.fishTime,1),nloci);
                final_output.allProteinSignals(:,1)= output.proteinSignal;
                for i =2:nloci
	                output = Ecoli_geneExpression_TASEP_function(param);
	                fish1_signals(:,i,1) = output.fish5Signal;
	                fish1_signals(:,i,2) = output.fish3Signal;
                    final_output.allProteinSignals(:,i)= output.proteinSignal;
                    gstar_signals(:,i) = output.Gstar_Signal;
                    gstar_fishSignals = gstar_fishSignals +output.Gstar_fishSignals;
	                final_output.proteinSignal = final_output.proteinSignal + output.proteinSignal;
	                final_output.transcribing_5signal = final_output.transcribing_5signal +output.transcribing_5signal;
	                final_output.DNAStats(:,i) = output.DNAStats;
	                final_output.FirstRiboRNAPdistance = [final_output.FirstRiboRNAPdistance;output.FirstRiboRNAPdistance];
	                final_output.RiboperRNAP = [final_output.RiboperRNAP;output.RiboperRNAP];
                    final_output.RNAP_startTimes = [final_output.RNAP_startTimes,output.RNAP_startTimes];
                    final_output.RNAP_endTimes = [final_output.RNAP_endTimes,output.RNAP_endTimes];
                    final_output.RNAlifetimes = [final_output.RNAlifetimes,output.RNAlifetimes];
	                final_output.prematureterm = final_output.prematureterm + output.prematureterm;
	                final_output.RNAlifetimes_analysis =[final_output.RNAlifetimes_analysis,output.RNAlifetimes_analysis];
	                final_output.mu1lifetimes =[final_output.mu1lifetimes,output.mu1lifetimes];
	                final_output.mu2lifetimes =[final_output.mu2lifetimes,output.mu2lifetimes];
                    final_output.ribo_loadTimes = [final_output.ribo_loadTimes;output.ribo_loadTimes];
                    final_output.ribo_loadTimes2 = [final_output.ribo_loadTimes2;output.ribo_loadTimes2];
                    final_output.RNAseq = final_output.RNAseq + output.RNAseq;
                    final_output.crazyfish = final_output.crazyfish +output.crazyfish;
                    final_output.crazyGstar = final_output.crazyGstar +output.crazyGstar;
                    final_output.NETseq = final_output.NETseq + output.NETseq;
                    final_output.Sendseq = [final_output.Sendseq;output.Sendseq];
                    final_output.gstar_5_breakdown = final_output.gstar_5_breakdown + output.gstar_5_breakdown;
                    final_output.gstar_3_breakdown = final_output.gstar_3_breakdown + output.gstar_3_breakdown;
                    final_output.gstar_bp1_breakdown = final_output.gstar_bp1_breakdown + output.gstar_bp1_breakdown;
                    app.batchGauge.Value = i;
                    totalTimeElapsed = totalTimeElapsed + output.timeElapsed;
                    drawnow;
                end
                final_output.crazyfish = final_output.crazyfish/nloci;
                final_output.crazyGstar = final_output.crazyGstar/nloci;
                final_output.fish5Signal = mean (fish1_signals(:,:,1),2);
                final_output.fish5FanoFactor = var(fish1_signals(:,:,1),0,2)./final_output.fish5Signal;
                final_output.fish3Signal = mean (fish1_signals(:,:,2),2);
                final_output.fish3FanoFactor = var(fish1_signals(:,:,2),0,2)./final_output.fish3Signal;
                final_output.GstarSignal = mean (gstar_signals(:,:),2);
                final_output.gstar_fishSignals =gstar_fishSignals/nloci;
                final_output.fish5Signalerr=std(fish1_signals(:,:,1),0,2)/sqrt(nloci);
                final_output.fish3Signalerr=std(fish1_signals(:,:,2),0,2)/sqrt(nloci);
                final_output.GstarSignalerr=std(gstar_signals(:,:),0,2)/sqrt(nloci);
                final_output.proteinSignal = final_output.proteinSignal/nloci;
                final_output.RNAseq = final_output.RNAseq/nloci;
                final_output.NETseq = final_output.NETseq/nloci;
                final_output.gstar_5_breakdown = final_output.gstar_5_breakdown/nloci;
                final_output.gstar_3_breakdown = final_output.gstar_3_breakdown/nloci;
                final_output.gstar_bp1_breakdown = final_output.gstar_bp1_breakdown/nloci;
                final_output.nloci = nloci;
                final_output.totalHoursElapsed = totalTimeElapsed/3600;
                filename = num2str(geneNo) +"_"+ string(datetime('now','Format','yyyyMMdd_HHmm'))+ "_" + app.batchSimParam.gene(geneNo);
                final_output.filename = filename;
                %%deets = mat2str(parameters);
                filename = ""+ foldername + "/"+ filename;
                save((app.savepath +"/"+ filename+".mat"),'final_output');
                %%writelines(deets,("data\"+filename+".txt"));
            end
            app.batchIdleLabel.Text = "simulation done";

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {733, 733};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {335, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export...';
            app.ExportcurrentTabMenu = uimenu(app.ExportMenu);
            app.ExportcurrentTabMenu.Text = 'Export current Tab';
            app.ExportalltabsMenu = uimenu(app.ExportMenu);
            app.ExportalltabsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportalltabsMenuSelected, true);
            app.ExportalltabsMenu.Text = 'Export all tabs';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataviewerPanel = uipanel(app.LeftPanel);
            app.DataviewerPanel.Title = 'Data viewer';
            app.ImportdataButton = uibutton(app.DataviewerPanel, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.FilenameDropDownLabel = uilabel(app.DataviewerPanel);
            app.FilenameDropDownLabel.Text = 'File name';
            app.FilenameDropDown = uidropdown(app.DataviewerPanel);
            app.FilenameDropDown.Items = {};
            app.FilenameDropDown.ValueChangedFcn = createCallbackFcn(app, @FilenameDropDownValueChanged, true);
            app.FilenameDropDown.Placeholder = 'Parameters';
            app.FilenameDropDown.Value = {};
            app.ImportallfilesfromfolderButton = uibutton(app.DataviewerPanel, 'push');
            app.ImportallfilesfromfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImportallfilesfromfolderButtonPushed, true);
            app.ImportallfilesfromfolderButton.Text = 'Import all files from folder';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.InputRatesTab = uitab(app.TabGroup2);
            app.InputRatesTab.Title = 'Input Rates';
            app.SimulationinputparametersPanel = uipanel(app.InputRatesTab);
            app.SimulationinputparametersPanel.Title = 'Simulation input parameters';
            app.RNAPspeedEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.RNAPspeedEditFieldLabel.Text = 'RNAP speed';
            app.RNAPspeedEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.RNAPspeedEditField.Limits = [0 Inf];
            app.RNAPspeedEditField.Value = 19;
            app.RNAPloadingrateEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.RNAPloadingrateEditFieldLabel.Text = 'RNAP loading rate ';
            app.RNAPloadingrateEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.RNAPloadingrateEditField.Value = 0.05;
            app.TotalsimulationtimeEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.TotalsimulationtimeEditFieldLabel.Text = 'Total simulation time';
            app.TotalsimulationtimeEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.TotalsimulationtimeEditField.Value = 2000;
            app.PromotorofftimeEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.PromotorofftimeEditFieldLabel.Text = 'Promotor off time ';
            app.PromotorofftimeEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.PromotorofftimeEditField.Value = 1500;
            app.mu21kd2Label = uilabel(app.SimulationinputparametersPanel);
            app.mu21kd2Label.Text = 'mu2 (1/kd2)';
            app.mu21kd2EditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.mu21kd2EditField.Value = 140;
            app.RibosomeloadingrateEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.RibosomeloadingrateEditFieldLabel.Text = 'Ribosome loading rate';
            app.RibosomeloadingrateEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.RibosomeloadingrateEditField.Value = 0.2;
            app.RibosomespeedEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.RibosomespeedEditFieldLabel.Text = 'Ribosome speed';
            app.RibosomespeedEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.RibosomespeedEditField.Value = 19;
            app.mu11kd1Label = uilabel(app.SimulationinputparametersPanel);
            app.mu11kd1Label.Text = 'mu1 (1/kd1)';
            app.mu11kd1EditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.mu11kd1EditField.Value = 240;
            app.RholoadingrateEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.RholoadingrateEditFieldLabel.Text = 'Rho loading rate';
            app.RholoadingrateEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.RholoadingrateEditField.Limits = [0 Inf];
            app.RholoadingrateEditField.Value = 0.13;
            app.StartsimulationButton = uibutton(app.SimulationinputparametersPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.IdleLabel = uilabel(app.SimulationinputparametersPanel);
            app.IdleLabel.Text = 'Idle';
            app.NumberofiterationsEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.NumberofiterationsEditField.Limits = [0 Inf];
            app.NumberofiterationsEditField.Value = 300;
            app.SelectsavefolderButton = uibutton(app.SimulationinputparametersPanel, 'push');
            app.SelectsavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsavefolderButtonPushed, true);
            app.SelectsavefolderButton.Text = 'Select save folder';
            app.Gauge = uigauge(app.SimulationinputparametersPanel, 'linear');
            app.muPT1kdPTLabel = uilabel(app.SimulationinputparametersPanel);
            app.muPT1kdPTLabel.Text = 'muPT (1/kdPT)';
            app.muPT1kdPTEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.muPT1kdPTEditField.Value = 140;
            app.EstimatedtImeremainingLabel = uilabel(app.SimulationinputparametersPanel);
            app.EstimatedtImeremainingLabel.Text = 'Estimated tIme remaining';
            app.HoursLabel = uilabel(app.SimulationinputparametersPanel);
            app.HoursLabel.Text = '0 Hours';
            app.sLabel = uilabel(app.SimulationinputparametersPanel);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.SimulationinputparametersPanel);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.SimulationinputparametersPanel);
            app.sLabel_3.Text = 's';
            app.sLabel_4 = uilabel(app.SimulationinputparametersPanel);
            app.sLabel_4.Text = 's';
            app.ntsLabel = uilabel(app.SimulationinputparametersPanel);
            app.ntsLabel.Text = 'nt/s';
            app.s1Label = uilabel(app.SimulationinputparametersPanel);
            app.s1Label.Text = 's^-1';
            app.s1Label_2 = uilabel(app.SimulationinputparametersPanel);
            app.s1Label_2.Text = 's^-1';
            app.s1Label_3 = uilabel(app.SimulationinputparametersPanel);
            app.s1Label_3.Text = 's^-1';
            app.ntsLabel_2 = uilabel(app.SimulationinputparametersPanel);
            app.ntsLabel_2.Text = 'nt/s';
            app.sLabel_5 = uilabel(app.SimulationinputparametersPanel);
            app.sLabel_5.Text = 's';
            app.lociLabel = uilabel(app.SimulationinputparametersPanel);
            app.lociLabel.Text = 'loci';
            app.sLabel_6 = uilabel(app.SimulationinputparametersPanel);
            app.sLabel_6.Text = 's';
            app.PromotorontimeEditFieldLabel = uilabel(app.SimulationinputparametersPanel);
            app.PromotorontimeEditFieldLabel.Text = 'Promotor on time ';
            app.PromotorontimeEditField = uieditfield(app.SimulationinputparametersPanel, 'numeric');
            app.PromotorontimeEditField.Editable = 'off';
            app.InputRNAPdwelltimeTab = uitab(app.TabGroup2);
            app.InputRNAPdwelltimeTab.Title = 'Input RNAP dwell time';
            app.dwelltime_Axes = uiaxes(app.InputRNAPdwelltimeTab);
            title(app.dwelltime_Axes, 'Dwelltime distribution')
            xlabel(app.dwelltime_Axes, 'Base position (nt)')
            ylabel(app.dwelltime_Axes, 'Relative dwell time (AU)')
            zlabel(app.dwelltime_Axes, 'Z')
            app.RNAPprofileDropDownLabel = uilabel(app.InputRNAPdwelltimeTab);
            app.RNAPprofileDropDownLabel.Text = 'RNAP profile';
            app.RNAPprofileDropDown = uidropdown(app.InputRNAPdwelltimeTab);
            app.RNAPprofileDropDown.Items = {'Uniform', 'Imported Data'};
            app.RNAPprofileDropDown.ItemsData = [0 1 2 3 4 5 6 7];
            app.RNAPprofileDropDown.ValueChangedFcn = createCallbackFcn(app, @RNAPprofileDropDownValueChanged, true);
            app.RNAPprofileDropDown.Value = 0;
            app.ImportdwelltimedistributionButton = uibutton(app.InputRNAPdwelltimeTab, 'push');
            app.ImportdwelltimedistributionButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdwelltimedistributionButtonPushed, true);
            app.ImportdwelltimedistributionButton.Text = 'Import dwelltime distribution';
            app.GenelengthEditFieldLabel = uilabel(app.InputRNAPdwelltimeTab);
            app.GenelengthEditFieldLabel.Text = 'Gene length';
            app.GenelengthEditField = uieditfield(app.InputRNAPdwelltimeTab, 'numeric');
            app.GenelengthEditField.Limits = [1 Inf];
            app.GenelengthEditField.RoundFractionalValues = 'on';
            app.GenelengthEditField.Value = 3075;
            app.ExtraBatchsimulationTab = uitab(app.TabGroup2);
            app.ExtraBatchsimulationTab.Title = 'Extra: Batch simulation';
            app.ImportparameterfileButton = uibutton(app.ExtraBatchsimulationTab, 'push');
            app.ImportparameterfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportparameterfileButtonPushed, true);
            app.ImportparameterfileButton.Text = 'Import parameter file';
            app.batchGauge = uigauge(app.ExtraBatchsimulationTab, 'linear');
            app.batchIdleLabel = uilabel(app.ExtraBatchsimulationTab);
            app.batchIdleLabel.Text = 'Idle';
            app.StartbatchsimulationButton = uibutton(app.ExtraBatchsimulationTab, 'push');
            app.StartbatchsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartbatchsimulationButtonPushed, true);
            app.StartbatchsimulationButton.Text = 'Start batch simulation';
            app.CurrentgenesimulationLabel = uilabel(app.ExtraBatchsimulationTab);
            app.CurrentgenesimulationLabel.Text = 'Current gene simulation:';
            app.batchSimTable = uitable(app.ExtraBatchsimulationTab);
            app.batchSimTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.batchSimTable.RowName = {};
            app.SelectgenefolderButton = uibutton(app.ExtraBatchsimulationTab, 'push');
            app.SelectgenefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgenefolderButtonPushed, true);
            app.SelectgenefolderButton.Text = 'Select gene folder';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SEndseqTab = uitab(app.TabGroup);
            app.SEndseqTab.Title = 'SEnd-seq';
            app.sendSEQcoverage_Axes = uiaxes(app.SEndseqTab);
            ylabel(app.sendSEQcoverage_Axes, 'Relative signal')
            zlabel(app.sendSEQcoverage_Axes, 'Z')
            app.sendSEQ_Axes = uiaxes(app.SEndseqTab);
            title(app.sendSEQ_Axes, 'SEnd-seq')
            xlabel(app.sendSEQ_Axes, 'Base position (nt)')
            ylabel(app.sendSEQ_Axes, 'RNA #')
            zlabel(app.sendSEQ_Axes, 'Z')
            app.RifseqTab = uitab(app.TabGroup);
            app.RifseqTab.Title = 'Rif-seq';
            app.rifseq_Axes = uiaxes(app.RifseqTab);
            title(app.rifseq_Axes, 'Simulated Rif-seq data')
            xlabel(app.rifseq_Axes, 'Time (s)')
            ylabel(app.rifseq_Axes, 'log(Relative signal)')
            zlabel(app.rifseq_Axes, 'Z')
            app.HoldgraphCheckBox_2 = uicheckbox(app.RifseqTab);
            app.HoldgraphCheckBox_2.Text = 'Hold graph';
            app.NETseqTab = uitab(app.TabGroup);
            app.NETseqTab.Title = 'NET-seq';
            app.NETseq_Axes = uiaxes(app.NETseqTab);
            title(app.NETseq_Axes, 'Simulated NET-seq result')
            xlabel(app.NETseq_Axes, 'Base position (nt)')
            ylabel(app.NETseq_Axes, 'Reads (AU)')
            zlabel(app.NETseq_Axes, 'Z')
            app.PlotDropDownLabel_2 = uilabel(app.NETseqTab);
            app.PlotDropDownLabel_2.Text = 'Plot:';
            app.NETseq_dropdown = uidropdown(app.NETseqTab);
            app.NETseq_dropdown.Items = {'NETseq at specified time', 'NETseq all time'};
            app.NETseq_dropdown.ItemsData = [1 2 3 4 5];
            app.NETseq_dropdown.ValueChangedFcn = createCallbackFcn(app, @crazyfish_PlotDropDownValueChanged, true);
            app.NETseq_dropdown.Value = 2;
            app.NETseqtimesEditFieldLabel = uilabel(app.NETseqTab);
            app.NETseqtimesEditFieldLabel.Text = 'NETseq time(s)';
            app.NETseqtimesEditField = uieditfield(app.NETseqTab, 'numeric');
            app.NETseqtimesEditField.Limits = [0 Inf];
            app.NETseqtimesEditField.RoundFractionalValues = 'on';
            app.NETseqtimesEditField.ValueChangedFcn = createCallbackFcn(app, @crazyfish_PlotDropDownValueChanged, true);
            app.NETseqtimesEditField.Value = 30;
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.Tree = uitree(app.ParametersTab);
            app.ParametersNode = uitreenode(app.Tree);
            app.ParametersNode.Text = 'Parameters';
            app.VelocitiesNode = uitreenode(app.ParametersNode);
            app.VelocitiesNode.Text = 'Velocities';
            app.v1Node = uitreenode(app.VelocitiesNode);
            app.v1Node.NodeData = 22;
            app.v1Node.Text = 'v1';
            app.v2Node = uitreenode(app.VelocitiesNode);
            app.v2Node.Text = 'v2';
            app.RiboSpeedNode = uitreenode(app.VelocitiesNode);
            app.RiboSpeedNode.Text = 'RiboSpeed';
            app.KvaluesNode = uitreenode(app.ParametersNode);
            app.KvaluesNode.Text = 'K values';
            app.KRNAPLoadingNode = uitreenode(app.KvaluesNode);
            app.KRNAPLoadingNode.Text = 'KRNAPLoading';
            app.KRiboLoadingNode = uitreenode(app.KvaluesNode);
            app.KRiboLoadingNode.Text = 'KRiboLoading';
            app.KRUTLoadingNode = uitreenode(app.KvaluesNode);
            app.KRUTLoadingNode.Text = 'KRUTLoading';
            app.MuValuesNode = uitreenode(app.ParametersNode);
            app.MuValuesNode.Text = 'Mu Values';
            app.coTranscriptionalMuNode = uitreenode(app.MuValuesNode);
            app.coTranscriptionalMuNode.Text = 'co-Transcriptional Mu';
            app.postTranscriptionalMuNode = uitreenode(app.MuValuesNode);
            app.postTranscriptionalMuNode.Text = 'post-Transcriptional Mu';
            app.ProteinmuNode = uitreenode(app.MuValuesNode);
            app.ProteinmuNode.Text = 'Protein mu';
            app.OtherParametersNode = uitreenode(app.ParametersNode);
            app.OtherParametersNode.Text = 'Other Parameters';
            app.GlucoseAdditionTimeNode = uitreenode(app.OtherParametersNode);
            app.GlucoseAdditionTimeNode.Text = 'Glucose Addition Time';
            app.NumberoflociNode = uitreenode(app.OtherParametersNode);
            app.NumberoflociNode.Text = 'Number of loci';
            app.RiboFollowingNode = uitreenode(app.OtherParametersNode);
            app.RiboFollowingNode.Text = 'RiboFollowing';
            app.SimulationTimeNode = uitreenode(app.OtherParametersNode);
            app.SimulationTimeNode.Text = 'Simulation Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ecoli_geneExpression_TASEP_dataViewer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end